classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ButtonGroup              matlab.ui.container.ButtonGroup
        Button3                  matlab.ui.control.ToggleButton
        Button2_2                matlab.ui.control.ToggleButton
        Button_2                 matlab.ui.control.ToggleButton
        ProvedidetekcijuButton   matlab.ui.control.Button
        SlikajButton             matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        ListBoxLabel             matlab.ui.control.Label
        Tree                     matlab.ui.container.CheckBoxTree
        Node                     matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        AUTOMATSKINAINRADALabel  matlab.ui.control.Label
        natragButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: natragButton
        function natragButtonPushed(app, event)
          

        % Prikažite drugi app
        delete(app);
        end

        % Button pushed function: SlikajButton
        function SlikajButtonPushed(app, event)
            %% assignment 1 - get images
camera_info = imaqhwinfo('winvideo');
% use camera_info = imaqhwinfo('winvideo') on Windows machine

% uncomment lines below if you get memory issue errors
% imaqreset;
% imaqmex('feature','-limitPhysicalMemoryUsage',false);
info = imaqhwinfo;

% Display installed adaptors
disp('Installed Adaptors:');
disp(info.InstalledAdaptors);

% Get information on a specific adaptor
cameraInfo = imaqhwinfo('winvideo'); % Replace 'winvideo' with your camera adaptor if different
disp('Camera Info:');
disp(cameraInfo);

% Create a video input object
video_obj = videoinput('winvideo', 2); % Adjust 'winvideo' and 1 based on your setup

% Get the selected source object
src = getselectedsource(video_obj);

% Display all properties of the source object
disp('Camera Properties:');
disp(get(src));

%video_obj = videoinput('winvideo', camera_info.DeviceInfo.DeviceID, camera_info.DeviceInfo.DefaultFormat);

video_obj.ReturnedColorSpace = 'rgb';
f = figure('Visible', 'off'); 
vidRes = video_obj.VideoResolution;
imageRes = fliplr(vidRes);
hImage = imshow(zeros(imageRes));
num_images = 20;
for i=1:num_images
    % preview video object
    preview(video_obj, hImage);
    waitforbuttonpress;
    stoppreview(video_obj);
    % start to enable grab
    start(video_obj);
    img = getdata(video_obj);
    img = img(:, :, (1:3));
    imwrite(img, "calib_img"+num2str(i)+".png")
    stop(video_obj);
end

%clear video_obj
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.natragButton = uibutton(app.UIFigure, 'push');
            app.natragButton.ButtonPushedFcn = createCallbackFcn(app, @natragButtonPushed, true);
            app.natragButton.Text = 'natrag';
            app.AUTOMATSKINAINRADALabel = uilabel(app.UIFigure);
            app.AUTOMATSKINAINRADALabel.Text = 'AUTOMATSKI NAČIN RADA ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.SlikajButton = uibutton(app.UIFigure, 'push');
            app.SlikajButton.ButtonPushedFcn = createCallbackFcn(app, @SlikajButtonPushed, true);
            app.SlikajButton.Text = 'Slikaj';
            app.ProvedidetekcijuButton = uibutton(app.UIFigure, 'push');
            app.ProvedidetekcijuButton.Text = 'Provedi detekciju';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2_2 = uitogglebutton(app.ButtonGroup);
            app.Button2_2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end