classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BRZINAButtonGroup         matlab.ui.container.ButtonGroup
        Button_4                  matlab.ui.control.RadioButton
        Button_3                  matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        Button                    matlab.ui.control.RadioButton
        PreraunajButton_2         matlab.ui.control.Button
        Gauge_3                   matlab.ui.control.Gauge
        Gauge_2                   matlab.ui.control.Gauge
        Gauge                     matlab.ui.control.Gauge
        natragButton              matlab.ui.control.Button
        motor3_inv                matlab.ui.control.EditField
        motor1EditField_2Label_3  matlab.ui.control.Label
        motor2_inv                matlab.ui.control.EditField
        motor1EditField_2Label_2  matlab.ui.control.Label
        motor1_inv                matlab.ui.control.EditField
        motor1EditField_2Label    matlab.ui.control.Label
        POZICIJALabel             matlab.ui.control.Label
        zEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel           matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        xEditField                matlab.ui.control.NumericEditField
        xEditFieldLabel           matlab.ui.control.Label
        UnesieljenikutzaLabel_2   matlab.ui.control.Label
        VektororijentacijeizvrnoglanaEditField  matlab.ui.control.EditField
        VektororijentacijeizvrnoglanaEditFieldLabel  matlab.ui.control.Label
        VektorpozicijeizvrnoglanaEditField_2  matlab.ui.control.EditField
        VektorpozicijeizvrnoglanaEditField_2Label  matlab.ui.control.Label
        PreraunajButton           matlab.ui.control.Button
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        UnesieljenikutzaLabel     matlab.ui.control.Label
        RUNOUPRAVLJANJELabel      matlab.ui.control.Label
        EditField_5               matlab.ui.control.NumericEditField
        EditField_6               matlab.ui.control.NumericEditField
        EditField_4               matlab.ui.control.NumericEditField
        MOTOR3Slider              matlab.ui.control.Slider
        MOTOR3SliderLabel         matlab.ui.control.Label
        MOTOR2Slider              matlab.ui.control.Slider
        MOTOR2SliderLabel         matlab.ui.control.Label
        MOTOR1Slider              matlab.ui.control.Slider
        MOTOR1SliderLabel         matlab.ui.control.Label
        RESETButton               matlab.ui.control.Button
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
    end

    
    properties (Access = public)
        SimulinkModelNameConstant1 % Description
        SimulinkModelNameConstant2 % Description
        SimulinkModelNameConstant3 % Description
        SimulinkModelNameGain1 % Description
        SimulinkModelNameGain2 % Description
        SimulinkModelNameGain3 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulinkModelNameConstant1 = 'simulation_three_motors/Motor1';
            app.SimulinkModelNameConstant2 = 'simulation_three_motors/Motor2';
            app.SimulinkModelNameConstant3 = 'simulation_three_motors/Motor3';
            app.SimulinkModelNameGain1 = 'simulation_three_motors/Kp';
            app.SimulinkModelNameGain2 = 'simulation_three_motors/Kp1';
            app.SimulinkModelNameGain3 = 'simulation_three_motors/Kp2';
        end

        % Value changed function: MOTOR1Slider
        function MOTOR1SliderValueChanged(app, event)
            value = app.MOTOR1Slider.Value;
            set_param(app.SimulinkModelNameConstant1,'Value', mat2str(value));
            app.EditField_4.Value=value;
          
            
        end

        % Value changed function: MOTOR2Slider
        function MOTOR2SliderValueChanged(app, event)
            value = app.MOTOR2Slider.Value;
            set_param(app.SimulinkModelNameConstant2,'Value', mat2str(value));
            app.EditField_6.Value=value;
            
        end

        % Value changed function: MOTOR3Slider
        function MOTOR3SliderValueChanged(app, event)
            value = app.MOTOR3Slider.Value;
            set_param(app.SimulinkModelNameConstant3,'Value', mat2str(value));
            app.EditField_5.Value=value;
            

        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)


        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.EditField_4.Value=0;
            app.EditField_6.Value=0;
            app.EditField_5.Value=0;
            app.zEditField.Value = 0;
            app.yEditField.Value = 0;
            app.xEditField.Value = 0;
            app.MOTOR1Slider.Value=0;
            app.MOTOR2Slider.Value=0;
            app.MOTOR3Slider.Value=0;
            app.EditField.Value=0;
            app.EditField_2.Value=0;
            app.EditField_3.Value=0;
            app.VektororijentacijeizvrnoglanaEditField.Value = ' ';
            app.VektorpozicijeizvrnoglanaEditField_2.Value = ' ';
            app.motor1_inv.Value = '';
            app.motor2_inv.Value = '';
            app.motor3_inv.Value = '';
            set_param(app.SimulinkModelNameConstant1,'Value', mat2str(0));
            
            set_param(app.SimulinkModelNameConstant2,'Value', mat2str(0));
            
            set_param(app.SimulinkModelNameConstant3,'Value', mat2str(0));
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            set_param(app.SimulinkModelNameConstant1,'Value', mat2str(value));
            app.EditField_4.Value=value;
            app.MOTOR1Slider.Value=value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            set_param(app.SimulinkModelNameConstant2,'Value', mat2str(value));
            app.EditField_6.Value=value;
            app.MOTOR2Slider.Value=value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            set_param(app.SimulinkModelNameConstant3,'Value', mat2str(value));
            app.EditField_5.Value=value;
            app.MOTOR3Slider.Value=value;
        end

        % Button pushed function: PreraunajButton
        function PreraunajButtonPushed(app, event)
            %q1 - kut motora baze
            %q2 - kut motora između baze i ruke
            %q3 - kut motora koji rotira olovku

            q0 = app.EditField_4.Value;
            q1 = app.EditField_6.Value;
            q2 = app.EditField_5.Value;
            d0 = 88; %prave vrijednosti??
            d1 = 160;
            d2 = 197;
            q0 = q0/180*pi;
            q1 = q1/180*pi;
            q2 = q2/180*pi;

            C0 = cos(q0);
            S0 = sin(q0);
            C1 = cos(q1);
            S1 = sin(q1);
            C2 = cos(q2);
            S2 = sin(q2);

%racunanje vektora pozicije i orijentacije alata

            %P = [S0*S1*S2*d2 - C1*C2*S0*d2 - C1*S0*d1, C0*C1*d1 + C0*C1*C2*d2 - C0*S1*S2*d2, d0 - S1*d1 - C1*S2*d2 - C2*S1*d2];
            %O = [S0*S1*S2 - C1*C2*S0, C0*C1*C2 - C0*S1*S2, - C1*S2 - C2*S1];
            Px = round(S0*S1*S2*d2 - C1*C2*S0*d2 - C1*S0*d1,2);
            Py=round(C0*C1*d1 + C0*C1*C2*d2 - C0*S1*S2*d2,2);
            Pz=round(d0 - S1*d1 - C1*S2*d2 - C2*S1*d2,2);
            Ox=round(S0*S1*S2 - C1*C2*S0,2);
            Oy=round(-(C0*C1*C2 - C0*S1*S2),2);
            Oz=round(- C1*S2 - C2*S1,2);

            %app.VektorpozicijeizvrnoglanaEditField.Value=mat2str(P);
            %app.VektororijentacijeizvrnoglanaEditField.Value=O;
            app.VektorpozicijeizvrnoglanaEditField_2.Value=mat2str(Px)+"x + "+mat2str(Py)+"y + "+mat2str(Pz)+"z";
            app.VektororijentacijeizvrnoglanaEditField.Value=mat2str(Ox)+"x + "+mat2str(Oy)+"y + "+mat2str(Oz)+"z";
        end

        % Callback function
        function PreraunajButton_2Pushed(app, event)
            d0 = 88; 
            d1 = 160;
            d2 = 197;
            w1 = app.xEditField.Value;
            w2 = app.yEditField.Value;
            w3 = app.zEditField.Value;
         
            q0r = atan2(-w1, w2);
            p = sqrt(w1^2+w2^2+(d0-w3)^2);
            q2r = acos((p^2 - d1^2-d2^2)/(2*d1*d2));
            ac = acos((p^2+d1^2 - d2^2)/(2*p*d1));
            as = asin((d0-w3)/(sqrt((d0-w3)^2+w1^2+w2^2)));
            q1r = as-ac;
            
            %provjera ispravnosti
            if imag(q0r) ~= 0 || imag(q1r) ~= 0 || imag(q2r) ~= 0
                msg = "Koordinate su izvan dosega ruke!!!";
                error(msg);
            end

            %pretvorva radijana u stupnjeve
            q0 = q0r*180/pi;
            q1 = q1r*180/pi;
            q2 = q2r*180/pi;
            app.motor1_inv.Value = mat2str(q0);
            app.motor2_inv.Value = mat2str(q1);
            app.motor3_inv.Value = mat2str(q2);
            app.Gauge.Value = (q0);
            app.Gauge_2.Value = (q1);
            app.Gauge_3.Value = (q2);
            
            set_param(app.SimulinkModelNameConstant1,'Value', mat2str(q0));
            set_param(app.SimulinkModelNameConstant2,'Value', mat2str(q1));
            set_param(app.SimulinkModelNameConstant3,'Value', mat2str(q2));
           
        end

        % Button pushed function: natragButton
        function natragButtonPushed(app, event)
            % Kreirajte instancu drugog app-a
        
        delete(app)
        end

        % Button pushed function: PreraunajButton_2
        function PreraunajButton_2Pushed2(app, event)
            w1 = app.xEditField.Value;
            w2 = app.yEditField.Value;
            w3 = app.zEditField.Value;
            P = [w1, w2, w3];
            [q0, q1, q2] = InverseKinematics(P);

            app.motor1_inv.Value = mat2str(q0);
            app.motor2_inv.Value = mat2str(q1);
            app.motor3_inv.Value = mat2str(q2);
            app.Gauge.Value = (q0);
            app.Gauge_2.Value = (q1);
            app.Gauge_3.Value = (q2);
            
            set_param(app.SimulinkModelNameConstant1,'Value', mat2str(q0));
            set_param(app.SimulinkModelNameConstant2,'Value', mat2str(q1));
            set_param(app.SimulinkModelNameConstant3,'Value', mat2str(q2));
        end

        % Selection changed function: BRZINAButtonGroup
        function BRZINAButtonGroupSelectionChanged(app, event)
            selectedButton = app.BRZINAButtonGroup.SelectedObject;
            if selectedButton==app.Button
                set_param(app.SimulinkModelNameGain1,'Gain', mat2str(0.25));
                set_param(app.SimulinkModelNameGain2,'Gain', mat2str(0.25))
                set_param(app.SimulinkModelNameGain3,'Gain', mat2str(0.25))
            elseif selectedButton == app.Button_2
                set_param(app.SimulinkModelNameGain1,'Gain', mat2str(0.5));
                set_param(app.SimulinkModelNameGain2,'Gain', mat2str(0.5))
                set_param(app.SimulinkModelNameGain3,'Gain', mat2str(0.5))
            elseif selectedButton == app.Button_4
               set_param(app.SimulinkModelNameGain1,'Gain', mat2str(1.5));
               set_param(app.SimulinkModelNameGain2,'Gain', mat2str(1.5))
               set_param(app.SimulinkModelNameGain3,'Gain', mat2str(1.5))
            elseif selectedButton == app.Button_3
               set_param(app.SimulinkModelNameGain1,'Gain', mat2str(1));
               set_param(app.SimulinkModelNameGain2,'Gain', mat2str(1))
               set_param(app.SimulinkModelNameGain3,'Gain', mat2str(1))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.MOTOR1SliderLabel = uilabel(app.UIFigure);
            app.MOTOR1SliderLabel.Text = 'MOTOR1';
            app.MOTOR1Slider = uislider(app.UIFigure);
            app.MOTOR1Slider.Limits = [-180 180];
            app.MOTOR1Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR1SliderValueChanged, true);
            app.MOTOR2SliderLabel = uilabel(app.UIFigure);
            app.MOTOR2SliderLabel.Text = 'MOTOR2';
            app.MOTOR2Slider = uislider(app.UIFigure);
            app.MOTOR2Slider.Limits = [-180 180];
            app.MOTOR2Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR2SliderValueChanged, true);
            app.MOTOR3SliderLabel = uilabel(app.UIFigure);
            app.MOTOR3SliderLabel.Text = 'MOTOR3';
            app.MOTOR3Slider = uislider(app.UIFigure);
            app.MOTOR3Slider.Limits = [-180 180];
            app.MOTOR3Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR3SliderValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.RUNOUPRAVLJANJELabel = uilabel(app.UIFigure);
            app.RUNOUPRAVLJANJELabel.Text = 'RUČNO UPRAVLJANJE';
            app.UnesieljenikutzaLabel = uilabel(app.UIFigure);
            app.UnesieljenikutzaLabel.Text = 'Unesi željeni kut za kut motora baze (MOTOR1), kut motora između baze i ruke (MOTOR2), kut motora koji rotira olovku (MOTOR3) ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [-180 180];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [-180 180];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [-180 180];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.PreraunajButton = uibutton(app.UIFigure, 'push');
            app.PreraunajButton.ButtonPushedFcn = createCallbackFcn(app, @PreraunajButtonPushed, true);
            app.PreraunajButton.Text = 'Preračunaj';
            app.VektorpozicijeizvrnoglanaEditField_2Label = uilabel(app.UIFigure);
            app.VektorpozicijeizvrnoglanaEditField_2Label.Text = 'Vektor pozicije izvršnog člana';
            app.VektorpozicijeizvrnoglanaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.VektororijentacijeizvrnoglanaEditFieldLabel = uilabel(app.UIFigure);
            app.VektororijentacijeizvrnoglanaEditFieldLabel.Text = 'Vektor orijentacije izvršnog člana';
            app.VektororijentacijeizvrnoglanaEditField = uieditfield(app.UIFigure, 'text');
            app.UnesieljenikutzaLabel_2 = uilabel(app.UIFigure);
            app.UnesieljenikutzaLabel_2.Text = 'Unesi željenu poziciju i orijentaciju izvršnog člana:';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.POZICIJALabel = uilabel(app.UIFigure);
            app.POZICIJALabel.Text = 'POZICIJA';
            app.motor1EditField_2Label = uilabel(app.UIFigure);
            app.motor1EditField_2Label.Text = 'motor1';
            app.motor1_inv = uieditfield(app.UIFigure, 'text');
            app.motor1EditField_2Label_2 = uilabel(app.UIFigure);
            app.motor1EditField_2Label_2.Text = 'motor2';
            app.motor2_inv = uieditfield(app.UIFigure, 'text');
            app.motor1EditField_2Label_3 = uilabel(app.UIFigure);
            app.motor1EditField_2Label_3.Text = 'motor3';
            app.motor3_inv = uieditfield(app.UIFigure, 'text');
            app.natragButton = uibutton(app.UIFigure, 'push');
            app.natragButton.ButtonPushedFcn = createCallbackFcn(app, @natragButtonPushed, true);
            app.natragButton.Text = 'natrag';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [-180 180];
            app.Gauge_2 = uigauge(app.UIFigure, 'circular');
            app.Gauge_2.Limits = [-180 180];
            app.Gauge_3 = uigauge(app.UIFigure, 'circular');
            app.Gauge_3.Limits = [-180 180];
            app.PreraunajButton_2 = uibutton(app.UIFigure, 'push');
            app.PreraunajButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreraunajButton_2Pushed2, true);
            app.PreraunajButton_2.Text = 'Preračunaj';
            app.BRZINAButtonGroup = uibuttongroup(app.UIFigure);
            app.BRZINAButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BRZINAButtonGroupSelectionChanged, true);
            app.BRZINAButtonGroup.Title = 'BRZINA';
            app.Button = uiradiobutton(app.BRZINAButtonGroup);
            app.Button.Text = '0.25';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.BRZINAButtonGroup);
            app.Button_2.Text = '0.5';
            app.Button_3 = uiradiobutton(app.BRZINAButtonGroup);
            app.Button_3.Text = '1';
            app.Button_4 = uiradiobutton(app.BRZINAButtonGroup);
            app.Button_4.Text = '1.5';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Gauge.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end