classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ColorPickerButton               matlab.ui.control.Button
        POKRENIButton                   matlab.ui.control.Button
        natragButton_2                  matlab.ui.control.Button
        AUTOMATSKINAINRADALabel_2       matlab.ui.control.Label
        IZBJEGNILabel                   matlab.ui.control.Label
        SPOJILabel                      matlab.ui.control.Label
        CheckBox88                      matlab.ui.control.CheckBox
        CheckBox8                       matlab.ui.control.CheckBox
        CheckBox77                      matlab.ui.control.CheckBox
        CheckBox66                      matlab.ui.control.CheckBox
        CheckBox55                      matlab.ui.control.CheckBox
        CheckBox7                       matlab.ui.control.CheckBox
        CheckBox6                       matlab.ui.control.CheckBox
        CheckBox5                       matlab.ui.control.CheckBox
        CheckBox44                      matlab.ui.control.CheckBox
        CheckBox4                       matlab.ui.control.CheckBox
        CheckBox33                      matlab.ui.control.CheckBox
        CheckBox22                      matlab.ui.control.CheckBox
        CheckBox11                      matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox1                       matlab.ui.control.CheckBox
        ProvedidetekcijuButton          matlab.ui.control.Button
        MaxConnectionDistanceEditField  matlab.ui.control.NumericEditField
        MaxConnectionDistanceEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        IzraunajputanjuButton           matlab.ui.control.Button
        SlikajButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
    end

    
    properties (Access = private)
         niz_zaobidi = {}; % inicijalizacija niza
         niz_spoji = {}; % inicijalizacija niza
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CheckBox1.Visible = "off";
            app.CheckBox11.Visible = "off";
            app.CheckBox2.Visible = "off";
            app.CheckBox22.Visible = "off";
            app.CheckBox3.Visible = "off";
            app.CheckBox33.Visible = "off";
            app.CheckBox4.Visible = "off";
            app.CheckBox44.Visible = "off";
            app.CheckBox5.Visible = "off";
            app.CheckBox55.Visible = "off";
            app.CheckBox6.Visible = "off";
            app.CheckBox66.Visible = "off";
            app.CheckBox7.Visible = "off";
            app.CheckBox77.Visible = "off";
            app.CheckBox8.Visible = "off";
            app.CheckBox88.Visible = "off";
            app.IZBJEGNILabel.Visible = "off";
            app.SPOJILabel.Visible = "off";
            
        end

        % Callback function
        function natragButtonPushed(app, event)
          

        % Prikažite drugi app
        delete(app);
        end

        % Button pushed function: IzraunajputanjuButton
        function IzraunajputanjuButtonPushed(app, event)
            scriptPath = 'RRTstar_final.m'; % Zamenite sa stvarnom putanjom
            
            % Pokrenite MATLAB skriptu
            run(scriptPath);
            
        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return;
            end
            
        
            item = selectedNode.Text;
            
            % Dodajte odabrani element u nizove
            app.niz_zaobidi{end+1} = item;
            
            for i = 1:length(app.Tree_2.Children)
                if strcmp(app.Tree_2.Children(i).Text, item)
                    app.Tree_2.Children(i).Visible = 'off';
                end
            end

            % Unutar callback funkcije dodajte:
            assignin('base', 'niz_zaobidi', app.niz_zaobidi);
           
            
            % Prikaz u Command Window-u za provjeru
            disp('Updated niz_zaobidi:');
            disp(app.niz_zaobidi);
           
        end

        % Value changed function: MaxIterationsEditField
        function MaxIterationsEditFieldValueChanged(app, event)
            newValue = app.MaxIterations.Value;
            assignin('base', 'MaxIterations', newValue);
            updateRRTStarParameters(app);
        end

        % Callback function
        function Tree_2CheckedNodesChanged(app, event)
             selectedNode = app.Tree_2.CheckedNodes;
             if isempty(selectedNode)
                return;
             end
        
            item = selectedNode.Text;
            
            % Dodajte odabrani element u nizove
            app.niz_spoji{end+1} = item;

            for i = 1:length(app.Tree.Children)
                if strcmp(app.Tree.Children(i).Text, item)
                    app.Tree.Children(i).Visible = 'off';
                end
            end
            %app.stopsignNode.Enable = 'off';
            % Unutar callback funkcije dodajte:
          
            assignin('base', 'niz_spoji', app.niz_spoji);
            
            % Prikaz u Command Window-u za provjeru
           
            disp('Updated niz_spoji:');
            disp(app.niz_spoji);
            
        end

        % Callback function
        function CheckBox_2ValueChanged(app, event)
            value = app.c2.Value;
            if value 
                app.CheckBox_10.Value = false;
                app.CheckBox_10.Visible = "off";
            end
        end

        % Callback function
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            if value 
                app.c2.Value = false;
                app.c2.Visible = "off";
            end
        end

        % Callback function
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value 
                app.CheckBox_11.Value = false;
                app.CheckBox_11.Visible = "off";
            end
            
        end

        % Callback function
        function CheckBox_11ValueChanged(app, event)
            value = app.CheckBox_11.Value;
            if value 
                app.CheckBox_3.Value = false;
                app.CheckBox_3.Visible = "off";
            end
            
        end

        % Callback function
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            if value 
                app.CheckBox_4.Value = false;
                app.CheckBox_4.Visible = "off";
            end
        end

        % Button pushed function: ProvedidetekcijuButton
        function ProvedidetekcijuButtonPushed(app, event)
            img = evalin('base', 'img');
            cameraParams = evalin('base', 'cameraParams');
            CV(img,cameraParams);


            % Očistite prethodne checkboxove
            app.CheckBox1.Visible = "off";
            app.CheckBox11.Visible = "off";
            app.CheckBox2.Visible = "off";
            app.CheckBox22.Visible = "off";
            app.CheckBox3.Visible = "off";
            app.CheckBox33.Visible = "off";
            app.CheckBox4.Visible = "off";
            app.CheckBox44.Visible = "off";
            app.CheckBox5.Visible = "off";
            app.CheckBox55.Visible = "off";
            app.CheckBox6.Visible = "off";
            app.CheckBox66.Visible = "off";
            app.CheckBox7.Visible = "off";
            app.CheckBox77.Visible = "off";
            app.CheckBox8.Visible = "off";
            app.CheckBox88.Visible = "off";
            
            app.IZBJEGNILabel.Visible = "on";
            app.SPOJILabel.Visible = "on";

            %scriptPath = 'CV.m'; % Zamenite sa stvarnom putanjom
            app.niz_spoji = {};
            assignin('base', 'niz_spoji', app.niz_spoji);
            app.niz_zaobidi = {};
            assignin('base', 'niz_zaobidi', app.niz_zaobidi);

            % Pokrenite MATLAB skriptu
            %run(scriptPath);

            masks = evalin('base', 'masks');
            
            % Za iteriranje kroz sve checkboxove i postavljanje tekstova prema 'Label' polju u 'masks'
            for i = 1:numel(masks)
                label = masks(i).Label;
                checkboxName = ['CheckBox', num2str(i)];
                % Dohvatimo odgovarajući checkbox
                checkbox = app.(checkboxName);
                checkbox.Visible = "on";
                % Postavimo tekst checkboxa
                checkbox.Text = label;
            end
            for i = 1:numel(masks)
                label = masks(i).Label;
                checkboxName = ['CheckBox', num2str(i), num2str(i)];
                % Dohvatimo odgovarajući checkbox
                checkbox = app.(checkboxName);
                checkbox.Visible = "on";
                % Postavimo tekst checkboxa
                checkbox.Text = label;
            end
        end

        % Value changed function: CheckBox1
        function CheckBox1ValueChanged(app, event)
            value = app.CheckBox1.Value;
            if value
                app.CheckBox11.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox1.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox1.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox11.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox1.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end

        end

        % Value changed function: CheckBox11
        function CheckBox11ValueChanged(app, event)
            value = app.CheckBox11.Value;
            if value
                app.CheckBox1.Visible ='off';
                
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox11.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox11.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox1.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox11.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
        end

        % Value changed function: MaxConnectionDistanceEditField
        function MaxConnectionDistanceEditFieldValueChanged(app, event)
            newValue = app.MaxConnectionDistance.Value;
            assignin('base', 'MaxConnectionDistance', newValue);
            updateRRTStarParameters(app);
        end

        % Button pushed function: natragButton_2
        function natragButton_2Pushed(app, event)
            % Prikažite drugi app
            delete(app);
        end

        % Callback function
        function ColorPickerValueChanged(app, event)
            value = app.ColorPicker.Value;
            components = app.UIFigure.Children;
            buttonComponents = findall(app.UIFigure, 'Type', 'uibutton');
            compColor = 1 - value; % Izračunavanje komplementarne boje
            pastelColor = value + (1 - value) * 0.7; % Izračunavanje pastelne boje

            if isequal(value, [0 0 0]) % Crna boja
                app.Image2.ImageSource = './markova_pozadina.jpg';
                app.Image2.Visible = 'on';
                for i = 1:length(components)
                    if isa(components(i), 'matlab.ui.control.Label')
                        components(i).FontColor = [0.38,0.92,0.88]; % Komplementarna boja za sve labele
                    end
                end
                for i = 1:length(buttonComponents)
                    buttonComponents(i).BackgroundColor = [0 0 0];
                    buttonComponents(i).FontColor = [1 1 0];
                end

            elseif isequal(value, [1 0 1]) % Roza boja
                app.Image2.ImageSource = './kad_mag_ureduje.png';
                app.Image2.Visible = 'on';
                for i = 1:length(components)
                    if isa(components(i), 'matlab.ui.control.Label')
                        components(i).FontColor = [0.90 0.16 0.51]; 
                    end
                end
                for i = 1:length(buttonComponents)
                    buttonComponents(i).BackgroundColor = [0.98,0.90,0.95];
                    buttonComponents(i).FontColor = [0 0 0];
                end
            else
                app.Image2.Visible = 'off'; % Uklanjanje slike
                app.UIFigure.Color = value;
                for i = 1:length(components)
                    if isa(components(i), 'matlab.ui.control.Label')
                        components(i).FontColor = compColor; % Komplementarna boja za sve labele
                    end
                end
                
                for i = 1:length(buttonComponents)
                    buttonComponents(i).BackgroundColor = pastelColor;
                    buttonComponents(i).FontColor = [0 0 0];
                end
            end
        end

        % Value changed function: CheckBox2
        function CheckBox2ValueChanged(app, event)
            value = app.CheckBox2.Value;
            if value
                app.CheckBox22.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox2.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox2.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox22.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox2.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
        end

        % Value changed function: CheckBox3
        function CheckBox3ValueChanged(app, event)
            value = app.CheckBox3.Value;
            if value
                app.CheckBox33.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox3.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox3.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox33.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox3.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
        end

        % Value changed function: CheckBox22
        function CheckBox22ValueChanged(app, event)
            value = app.CheckBox22.Value;
            
            if value
                app.CheckBox2.Visible ='off';
               
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox22.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox22.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox2.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox22.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
            
        end

        % Value changed function: CheckBox33
        function CheckBox33ValueChanged(app, event)
            value = app.CheckBox33.Value;
           
            if value
                app.CheckBox3.Visible ='off';
                
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox33.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox33.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox3.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox33.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
        
        end

        % Value changed function: CheckBox44
        function CheckBox44ValueChanged(app, event)
            value = app.CheckBox44.Value;
         
            if value
                app.CheckBox4.Visible ='off';
               
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox44.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox44.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox4.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox44.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
        
        end

        % Value changed function: CheckBox55
        function CheckBox55ValueChanged(app, event)
            value = app.CheckBox55.Value;
            
            if value
                app.CheckBox5.Visible ='off';
              
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox55.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox55.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox5.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox55.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
        
        end

        % Value changed function: CheckBox66
        function CheckBox66ValueChanged(app, event)
            value = app.CheckBox66.Value;
            
            if value
                app.CheckBox6.Visible ='off';
               
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox66.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox66.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox6.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox66.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
      
        end

        % Value changed function: CheckBox77
        function CheckBox77ValueChanged(app, event)
            value = app.CheckBox77.Value;
            
         
            if value
                app.CheckBox7.Visible ='off';
               
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox77.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox77.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox7.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox77.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
        end

        % Value changed function: CheckBox88
        function CheckBox88ValueChanged(app, event)
            value = app.CheckBox88.Value;
            
            if value
                app.CheckBox8.Visible ='off';
               
                indexToAdd = find(strcmp(app.niz_zaobidi, app.CheckBox88.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_zaobidi{end+1} = app.CheckBox88.Text;
                       assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end

            else
                app.CheckBox8.Visible='on';
                indexToRemove = find(strcmp(app.niz_zaobidi, app.CheckBox88.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_zaobidi(indexToRemove) = [];
                    assignin('base', 'niz_zaobidi', app.niz_zaobidi);
                end
            end
        end

        % Value changed function: CheckBox4
        function CheckBox4ValueChanged(app, event)
            value = app.CheckBox4.Value;
            
            if value
                app.CheckBox44.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox4.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox4.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox44.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox4.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
            
        end

        % Value changed function: CheckBox5
        function CheckBox5ValueChanged(app, event)
            value = app.CheckBox5.Value;

            if value
                app.CheckBox55.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox5.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox5.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox55.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox5.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
        end

        % Value changed function: CheckBox6
        function CheckBox6ValueChanged(app, event)
            value = app.CheckBox6.Value;
          
            if value
                app.CheckBox66.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox6.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox6.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox66.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox6.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
        end

        % Value changed function: CheckBox7
        function CheckBox7ValueChanged(app, event)
            value = app.CheckBox7.Value;
            
            if value
                app.CheckBox77.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox7.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox7.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox77.Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox7.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
        end

        % Value changed function: CheckBox8
        function CheckBox8ValueChanged(app, event)
            value = app.CheckBox8.Value;
          
            if value
                app.CheckBox88.Visible='off';
                indexToAdd = find(strcmp(app.niz_spoji, app.CheckBox8.Text));
                if isempty(indexToAdd)
                    % Ukloni element iz niza
                     app.niz_spoji{end+1} = app.CheckBox8.Text;
                       assignin('base', 'niz_spoji', app.niz_spoji);
                end

            else
                app.CheckBox88Visible='on';
                 indexToRemove = find(strcmp(app.niz_spoji, app.CheckBox8.Text));

                 %Provjeri je li element pronađen
                if ~isempty(indexToRemove)
                    % Ukloni element iz niza
                    app.niz_spoji(indexToRemove) = [];
                    assignin('base', 'niz_spoji', app.niz_spoji);
                end
            end
        end

        % Button pushed function: SlikajButton
        function SlikajButtonPushed(app, event)
            img = evalin('base', 'img');
            app.Image.ImageSource = img;
        end

        % Button pushed function: POKRENIButton
        function POKRENIButtonPushed(app, event)
            world_complete_path = evalin('base', 'world_complete_path2');
            points_to_world_automatski_rad(world_complete_path);
        end

        % Button pushed function: ColorPickerButton
        function ColorPickerButtonPushed(app, event)
            % Otvorite dijalog za odabir boje
            value = uisetcolor();

                % Provjerite je li korisnik odabrao boju ili otkazao dijalog
                if length(value) == 3
                    components = app.UIFigure.Children;
                    buttonComponents = findall(app.UIFigure, 'Type', 'uibutton');
                    compColor = 1 - value; % Izračunavanje komplementarne boje
                    pastelColor = value + (1 - value) * 0.7; % Izračunavanje pastelne boje
                
                    if isequal(value, [0 0 0]) % Crna boja
                        app.Image2.ImageSource = './markova_pozadina.jpg';
                        app.Image2.Visible = 'on';
                        for i = 1:length(components)
                            if isa(components(i), 'matlab.ui.control.Label')
                                components(i).FontColor = [0.38,0.92,0.88]; % Komplementarna boja za sve labele
                            end
                        end
                        for i = 1:length(buttonComponents)
                            buttonComponents(i).BackgroundColor = [0 0 0];
                            buttonComponents(i).FontColor = [1 1 0];
                        end
                
                    elseif isequal(value, [1 0 1]) % Roza boja
                        app.Image2.ImageSource = './kad_mag_ureduje.png';
                        app.Image2.Visible = 'on';
                        for i = 1:length(components)
                            if isa(components(i), 'matlab.ui.control.Label')
                                components(i).FontColor = [0.90 0.16 0.51]; 
                            end
                        end
                        for i = 1:length(buttonComponents)
                            buttonComponents(i).BackgroundColor = [0.98,0.90,0.95];
                            buttonComponents(i).FontColor = [0 0 0];
                        end
                    else
                        app.Image2.Visible = 'off'; % Uklanjanje slike
                        app.UIFigure.Color = value;
                        for i = 1:length(components)
                            if isa(components(i), 'matlab.ui.control.Label')
                                components(i).FontColor = compColor; % Komplementarna boja za sve labele
                            end
                        end
                
                        for i = 1:length(buttonComponents)
                            buttonComponents(i).BackgroundColor = pastelColor;
                            buttonComponents(i).FontColor = [0 0 0];
                        end
                    end
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'kad_mag_ureduje.png');
            app.Image = uiimage(app.UIFigure);
            app.SlikajButton = uibutton(app.UIFigure, 'push');
            app.SlikajButton.ButtonPushedFcn = createCallbackFcn(app, @SlikajButtonPushed, true);
            app.SlikajButton.Text = 'Slikaj';
            app.IzraunajputanjuButton = uibutton(app.UIFigure, 'push');
            app.IzraunajputanjuButton.ButtonPushedFcn = createCallbackFcn(app, @IzraunajputanjuButtonPushed, true);
            app.IzraunajputanjuButton.Text = 'Izračunaj putanju';
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'MaxIterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationsEditFieldValueChanged, true);
            app.MaxConnectionDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.MaxConnectionDistanceEditFieldLabel.Text = 'MaxConnectionDistance';
            app.MaxConnectionDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxConnectionDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MaxConnectionDistanceEditFieldValueChanged, true);
            app.ProvedidetekcijuButton = uibutton(app.UIFigure, 'push');
            app.ProvedidetekcijuButton.ButtonPushedFcn = createCallbackFcn(app, @ProvedidetekcijuButtonPushed, true);
            app.ProvedidetekcijuButton.Text = 'Provedi detekciju';
            app.CheckBox1 = uicheckbox(app.UIFigure);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = 'a';
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox2ValueChanged, true);
            app.CheckBox2.Text = 'b';
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox3ValueChanged, true);
            app.CheckBox3.Text = 'c';
            app.CheckBox11 = uicheckbox(app.UIFigure);
            app.CheckBox11.ValueChangedFcn = createCallbackFcn(app, @CheckBox11ValueChanged, true);
            app.CheckBox11.Text = 'a';
            app.CheckBox22 = uicheckbox(app.UIFigure);
            app.CheckBox22.ValueChangedFcn = createCallbackFcn(app, @CheckBox22ValueChanged, true);
            app.CheckBox22.Text = 'b';
            app.CheckBox33 = uicheckbox(app.UIFigure);
            app.CheckBox33.ValueChangedFcn = createCallbackFcn(app, @CheckBox33ValueChanged, true);
            app.CheckBox33.Text = 'c';
            app.CheckBox4 = uicheckbox(app.UIFigure);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox4ValueChanged, true);
            app.CheckBox4.Text = 'd';
            app.CheckBox44 = uicheckbox(app.UIFigure);
            app.CheckBox44.ValueChangedFcn = createCallbackFcn(app, @CheckBox44ValueChanged, true);
            app.CheckBox44.Text = 'd';
            app.CheckBox5 = uicheckbox(app.UIFigure);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox5ValueChanged, true);
            app.CheckBox5.Text = 'e';
            app.CheckBox6 = uicheckbox(app.UIFigure);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox6ValueChanged, true);
            app.CheckBox6.Text = 'f';
            app.CheckBox7 = uicheckbox(app.UIFigure);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox7ValueChanged, true);
            app.CheckBox7.Text = 'g';
            app.CheckBox55 = uicheckbox(app.UIFigure);
            app.CheckBox55.ValueChangedFcn = createCallbackFcn(app, @CheckBox55ValueChanged, true);
            app.CheckBox55.Text = 'e';
            app.CheckBox66 = uicheckbox(app.UIFigure);
            app.CheckBox66.ValueChangedFcn = createCallbackFcn(app, @CheckBox66ValueChanged, true);
            app.CheckBox66.Text = 'f';
            app.CheckBox77 = uicheckbox(app.UIFigure);
            app.CheckBox77.ValueChangedFcn = createCallbackFcn(app, @CheckBox77ValueChanged, true);
            app.CheckBox77.Text = 'g';
            app.CheckBox8 = uicheckbox(app.UIFigure);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @CheckBox8ValueChanged, true);
            app.CheckBox8.Text = 'h';
            app.CheckBox88 = uicheckbox(app.UIFigure);
            app.CheckBox88.ValueChangedFcn = createCallbackFcn(app, @CheckBox88ValueChanged, true);
            app.CheckBox88.Text = 'h';
            app.SPOJILabel = uilabel(app.UIFigure);
            app.SPOJILabel.Text = 'SPOJI';
            app.IZBJEGNILabel = uilabel(app.UIFigure);
            app.IZBJEGNILabel.Text = 'IZBJEGNI';
            app.AUTOMATSKINAINRADALabel_2 = uilabel(app.UIFigure);
            app.AUTOMATSKINAINRADALabel_2.Text = 'AUTOMATSKI NAČIN RADA ';
            app.natragButton_2 = uibutton(app.UIFigure, 'push');
            app.natragButton_2.ButtonPushedFcn = createCallbackFcn(app, @natragButton_2Pushed, true);
            app.natragButton_2.Text = 'natrag';
            app.POKRENIButton = uibutton(app.UIFigure, 'push');
            app.POKRENIButton.ButtonPushedFcn = createCallbackFcn(app, @POKRENIButtonPushed, true);
            app.POKRENIButton.Text = 'POKRENI';
            app.ColorPickerButton = uibutton(app.UIFigure, 'push');
            app.ColorPickerButton.ButtonPushedFcn = createCallbackFcn(app, @ColorPickerButtonPushed, true);
            app.ColorPickerButton.Text = 'Color Picker';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end