classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        AUTOMATSKINAINRADAButton  matlab.ui.control.Button
        RUNINAINRADAButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
         SimulinkModelNameConstant1
         SimulinkModelNameConstant2
         SimulinkModelNameConstant3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %run("init_3_dynamixels.m");
             % Otvori Simulink model
            run('simulation_three_motors.slx');
            run("load_parameters.m");
            app.SimulinkModelNameConstant1 = 'simulation_three_motors/Motor1';
            app.SimulinkModelNameConstant2 = 'simulation_three_motors/Motor2';
            app.SimulinkModelNameConstant3 = 'simulation_three_motors/Motor3';
            value=0;
            %set_param(app.SimulinkModelNameConstant1,'Value', mat2str(value));
            %set_param(app.SimulinkModelNameConstant2,'Value', mat2str(value));
            %set_param(app.SimulinkModelNameConstant3,'Value', mat2str(value));
            %set_param('simulation_three_motors', 'SimulationCommand', 'start');
        end

        % Button pushed function: AUTOMATSKINAINRADAButton
        function AUTOMATSKINAINRADAButtonPushed(app, event)
            secondaryApp = app2;
        % Prikažite drugi app
        secondaryApp.UIFigure.Visible = 'on';
        end

        % Button pushed function: RUNINAINRADAButton
        function RUNINAINRADAButtonPushed(app, event)
            primaryApp = app1;
        % Prikažite drugi app
        primaryApp.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RUNINAINRADAButton = uibutton(app.UIFigure, 'push');
            app.RUNINAINRADAButton.ButtonPushedFcn = createCallbackFcn(app, @RUNINAINRADAButtonPushed, true);
            app.RUNINAINRADAButton.Text = {'RUČNI NAČIN'; ' RADA'};
            app.AUTOMATSKINAINRADAButton = uibutton(app.UIFigure, 'push');
            app.AUTOMATSKINAINRADAButton.ButtonPushedFcn = createCallbackFcn(app, @AUTOMATSKINAINRADAButtonPushed, true);
            app.AUTOMATSKINAINRADAButton.Text = {'AUTOMATSKI '; 'NAČIN RADA'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'rukica.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end